classdef inicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Image3               matlab.ui.control.Image
        Image2               matlab.ui.control.Image
        DropDown             matlab.ui.control.DropDown
        Image                matlab.ui.control.Image
        IniciarsesionButton  matlab.ui.control.Button
        RegistrarseButton    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarsesionButton
        function IniciarsesionButtonPushed(app, event)
            delete(app);
            nuevaApp = LoginApp(iniciosesion); 
        end

        % Button pushed function: RegistrarseButton
        function RegistrarseButtonPushed(app, event)
            delete(app);
            nuevaApp = LoginApp(registrarse);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
        switch value
        case 'Español'
        
        case 'Inglés'
            delete(app);
            nuevaApp = LoginApp(inicioen); 
        case 'Euskara'
            delete(app);
            nuevaApp = LoginApp(inicioeu);   
         end
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            filePath = fullfile(pwd, 'InstruccionesPlasmoScan.pdf');
web(filePath, '-browser');
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            delete(app);
            nuevaApp = LoginApp(etiqueta);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RegistrarseButton = uibutton(app.UIFigure, 'push');
            app.RegistrarseButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrarseButtonPushed, true);
            app.RegistrarseButton.Text = 'Registrarse';
            app.IniciarsesionButton = uibutton(app.UIFigure, 'push');
            app.IniciarsesionButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarsesionButtonPushed, true);
            app.IniciarsesionButton.Text = 'Iniciar sesion';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo2.png');
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Español', 'Euskara', 'Inglés', ''};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Español';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end