classdef inicioeu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image3              matlab.ui.control.Image
        Image2              matlab.ui.control.Image
        DropDown            matlab.ui.control.DropDown
        Image               matlab.ui.control.Image
        SesioahasiButton    matlab.ui.control.Button
        ErregistratuButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SesioahasiButton
        function SesioahasiButtonPushed(app, event)
            delete(app);
            nuevaApp = LoginApp(iniciosesioneu); 
        end

        % Button pushed function: ErregistratuButton
        function ErregistratuButtonPushed(app, event)
            delete(app);
            nuevaApp = LoginApp(registrarseeu);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
        switch value
            case 'Gaztelania'
            delete(app);
            nuevaApp = LoginApp(inicio); 
            case 'Ingelesa'
            delete(app);
            nuevaApp = LoginApp(inicioen); 
        case 'Euskara'

         end
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            filePath = fullfile(pwd, 'InstruccionesPlasmoScan.pdf');
web(filePath, '-browser');
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            delete(app);
            nuevaApp = LoginApp(etiqueta);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ErregistratuButton = uibutton(app.UIFigure, 'push');
            app.ErregistratuButton.ButtonPushedFcn = createCallbackFcn(app, @ErregistratuButtonPushed, true);
            app.ErregistratuButton.Text = 'Erregistratu';
            app.SesioahasiButton = uibutton(app.UIFigure, 'push');
            app.SesioahasiButton.ButtonPushedFcn = createCallbackFcn(app, @SesioahasiButtonPushed, true);
            app.SesioahasiButton.Text = 'Sesioa hasi';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo2.png');
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Gaztelania', 'Euskara', 'Ingelesa', ''};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Euskara';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inicioeu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end