classdef iniciosesionen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image3                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Hyperlink               matlab.ui.control.Hyperlink
        DonthaveanaccountLabel  matlab.ui.control.Label
        LoginLabel              matlab.ui.control.Label
        UserEditField           matlab.ui.control.EditField
        UsuarioLabel            matlab.ui.control.Label
        PasswordEditField       matlab.ui.control.EditField
        ContraseaLabel          matlab.ui.control.Label
        SpecialtyDropDown       matlab.ui.control.DropDown
        SpecialtyDropDownLabel  matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        LoginButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
    function rolEsp = aEspanol(rolUI)
    % Traduce el valor del DropDown (inglés) a español
    switch string(rolUI)
        case "Doctor"
            rolEsp = "Médico";
        case "Laboratory technician"
            rolEsp = "Técnico de laboratorio";
        otherwise
            rolEsp = string(rolUI);   % ya venía en español u otro texto
    end
end
   archivo = fullfile(pwd,'usuarios.xlsx');

if ~isfile(archivo)
    uialert(app.UIFigure,"This user does not exist.","Error");
    return
end

datos = readcell(archivo);
contenido = datos(2:end,:);   % salta encabezados

usuarioIngresado   = string(app.UserEditField.Value);
contrasenaIngresada = string(app.PasswordEditField.Value);
rolIngresadoEsp   = aEspanol(app.SpecialtyDropDown.Value);  % ← traducido

coincide = false;
for i = 1:size(contenido,1)
    usuario = string(contenido{i,3});
    contrasena = string(contenido{i,4});
    rolExcel   = string(contenido{i,5});   % está en español

    if usuario == usuarioIngresado && ...
       contrasena == contrasenaIngresada && ...
       rolExcel == rolIngresadoEsp
        coincide = true;
        rolValidado = rolExcel;   % «Médico» o «Técnico de laboratorio»
        break
    end
end

if coincide
    switch rolValidado          % está en español, usa el que quieras
        case "Médico"
            delete(app);
            nuevaApp = LoginApp(medicoen);           % interfaz Médico-EN
        case "Técnico de laboratorio"
            delete(app);
            nuevaApp = LoginApp(tecnicoen);          % interfaz TecLab-EN
    end
else
    uialert(app.UIFigure,"Wrong User, Password or Specialty.","Error");
end
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(registrarse);           
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(inicio); 
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            filePath = fullfile(pwd, 'InstruccionesPlasmoScan.pdf');
web(filePath, '-browser');
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            delete(app);
            nuevaApp = LoginApp(etiqueta);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo2.png');
            app.SpecialtyDropDownLabel = uilabel(app.UIFigure);
            app.SpecialtyDropDownLabel.Text = 'Specialty';
            app.SpecialtyDropDown = uidropdown(app.UIFigure);
            app.SpecialtyDropDown.Items = {'Doctor', 'Laboratory technician'};
            app.SpecialtyDropDown.Value = 'Doctor';
            app.ContraseaLabel = uilabel(app.UIFigure);
            app.ContraseaLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.UsuarioLabel = uilabel(app.UIFigure);
            app.UsuarioLabel.Text = 'User';
            app.UserEditField = uieditfield(app.UIFigure, 'text');
            app.LoginLabel = uilabel(app.UIFigure);
            app.LoginLabel.Text = 'Login';
            app.DonthaveanaccountLabel = uilabel(app.UIFigure);
            app.DonthaveanaccountLabel.Text = 'Don''t have an account?';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Sign in';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iniciosesionen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end