classdef medicoen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DataregistrationTab           matlab.ui.container.Tab
        Image3_3                      matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        Image_3                       matlab.ui.control.Image
        SaveButton                    matlab.ui.control.Button
        PatienteIdEditField           matlab.ui.control.EditField
        PatientidLabel                matlab.ui.control.Label
        AgeEditField                  matlab.ui.control.NumericEditField
        EdadLabel                     matlab.ui.control.Label
        GenderDropDown                matlab.ui.control.DropDown
        SexoLabel                     matlab.ui.control.Label
        SurnameEditField              matlab.ui.control.EditField
        ApellidoLabel                 matlab.ui.control.Label
        NameEditField                 matlab.ui.control.EditField
        NombreLabel                   matlab.ui.control.Label
        PatientdataregistrationLabel  matlab.ui.control.Label
        DiagnoseTab                   matlab.ui.container.Tab
        Image3_2                      matlab.ui.control.Image
        Image2_2                      matlab.ui.control.Image
        NewpatientButton              matlab.ui.control.Button
        Label_4                       matlab.ui.control.Label
        ParasitemiaLabel              matlab.ui.control.Label
        SpeciesLabel                  matlab.ui.control.Label
        DiagnoseLabel                 matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        ResultsLabel                  matlab.ui.control.Label
        DateDatePicker                matlab.ui.control.DatePicker
        DateDatePickerLabel           matlab.ui.control.Label
        Image_2                       matlab.ui.control.Image
        Label_2                       matlab.ui.control.Label
        SpeciesButton                 matlab.ui.control.Button
        DiagnoseButton                matlab.ui.control.Button
        UploadphotoButton             matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        PatientIdEditField            matlab.ui.control.EditField
        IdpacienteLabel               matlab.ui.control.Label
        PatientdiagnosisLabel         matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        HistorialTab                  matlab.ui.container.Tab
        Image_4                       matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        Image2_3                      matlab.ui.control.Image
        PatientrecordLabel            matlab.ui.control.Label
        HistorialTable                matlab.ui.control.Table
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
    LastPacienteID
    LastFotoRuta
    LastFechaStr
    end
    
    methods (Access = private)
        function txt = getTextContentSafe(app, nodo, tag)
        elementos = nodo.getElementsByTagName(tag);
        if elementos.getLength > 0 && ~isempty(elementos.item(0).getFirstChild)
            txt = char(elementos.item(0).getFirstChild.getData);
        else
            txt = '-';
        end
        end
         function mostrarHistorialPaciente(app)
        % Cargar el XML
        xml = xmlread('pacientes.xml');
        pacienteID = lower(strtrim(app.LastPacienteID));

        pacientes = xml.getElementsByTagName('Paciente');

        historialData = []; % Para guardar filas

        for i = 0:pacientes.getLength-1
            p = pacientes.item(i);
            id = char(p.getElementsByTagName('ID').item(0).getTextContent);
            if strcmpi(strtrim(id), pacienteID)
                entradas = p.getElementsByTagName('Entrada');
                for j = 0:entradas.getLength-1
                    entrada = entradas.item(j);
                    
                    fecha     = app.getTextContentSafe(entrada, 'Fecha');
                    resultado = app.getTextContentSafe(entrada, 'Resultado');
                    especie   = app.getTextContentSafe(entrada, 'Especie');
                    
                    % Añadir fila
                    historialData = [historialData; {id, fecha, resultado, especie}];
                end
                break;
            end
        end


        % Mostrar en la tabla
        app.HistorialTable.Data = historialData;
        app.HistorialTable.ColumnName = {'ID', 'Fecha', 'Resultado', 'Especie'};
         end
    end

    % Función auxiliar para obtener texto seguro


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
archivoXML = 'pacientes.xml';
id = app.PatienteIdEditField.Value;
    nombre = app.NameEditField.Value;
    apellido = app.SurnameEditField.Value;
    edad = app.AgeEditField.Value;

  if isempty(regexp(id, '^\d{6,}$', 'once'))
    uialert(app.UIFigure, 'Invalid ID. It must have at least 6 numbers.', 'Error');
    return;
  end

if isempty(regexp(nombre, '^[A-Z][a-zA-Z]*$', 'once'))
    uialert(app.UIFigure, 'Invalid name. It must start with a capital.', 'Error');
    return;
end

if isempty(regexp(apellido, '^[A-Z][a-zA-Z]*$', 'once'))
    uialert(app.UIFigure, 'Invalid surname. It must start with a capital.', 'Error');
    return;
end

    if edad < 0 || edad > 120
        uialert(app.UIFigure, 'Age is out of range(0–120).', 'Error');
        return;
    end
% Verificar si el archivo existe y tiene contenido válido
if isfile(archivoXML) && dir(archivoXML).bytes > 0
    try
        doc = xmlread(archivoXML);
        root = doc.getDocumentElement;
    catch
        % Si el archivo existe pero está corrupto, lo recreamos
        doc = com.mathworks.xml.XMLUtils.createDocument('Pacientes');
        root = doc.getDocumentElement;
    end
else
    % Crear nuevo documento y nodo raíz <Pacientes>
    doc = com.mathworks.xml.XMLUtils.createDocument('Pacientes');
    root = doc.getDocumentElement;
end
   paciente = doc.createElement('Paciente');
    % Subnodos del paciente
    idNodo = doc.createElement('ID');
    idNodo.appendChild(doc.createTextNode( num2str(app.PatienteIdEditField.Value) ));
    paciente.appendChild(idNodo);
    nombre = doc.createElement('Nombre');
    nombre.appendChild(doc.createTextNode(app.NameEditField.Value));
    paciente.appendChild(nombre);

    apellido = doc.createElement('Apellido');
    apellido.appendChild(doc.createTextNode(app.SurnameEditField.Value));
    paciente.appendChild(apellido);

    edad = doc.createElement('Edad');
    edad.appendChild(doc.createTextNode(num2str(app.AgeEditField.Value)));
    paciente.appendChild(edad);
       
    if strcmp(app.GenderDropDown.Value, 'Woman')
    gender = 'Mujer';
    elseif strcmp(app.GenderDropDown.Value, 'Man')
    gender = 'Hombre';
    else
    gender = 'Otro';
    end    
    sexo = doc.createElement('Sexo');
    sexo.appendChild(doc.createTextNode(gender));  % O EditField si usas eso
    paciente.appendChild(sexo);

    % Añadir <Paciente> al nodo raíz <Pacientes>
    root.appendChild(paciente);

    % Guardar archivo actualizado
    xmlwrite(archivoXML, doc);
    app.NameEditField.Value    = '';
    app.SurnameEditField.Value  = '';
    app.AgeEditField.Value      = 0;   % o '' si tu EditField es de texto
    app.GenderDropDown.Value       = app.GenderDropDown.Items{1};
    app.PatienteIdEditField.Value  = '';
    % Confirmación
    uialert(app.UIFigure, 'Patient successfully saved', 'Success', "Icon","success");
        end

        % Button pushed function: UploadphotoButton
        function UploadphotoButtonPushed2(app, event)
            [file, path] = uigetfile({ ...
    '*.jpg;*.jpeg;*.png;*.tif;*.tiff', ...
    'Imágenes (*.jpg, *.jpeg, *.png, *.tif, *.tiff)'}, ...
    'Selecciona una imagen');

if isequal(file,0)
    uialert(app.UIFigure,'There was not selected any image.','Aviso');
    return;
end

% Asegúrate de que exista la carpeta ./imagenes
rutaProyecto = fileparts(mfilename('fullpath'));
rutaImagenes = fullfile(rutaProyecto, 'imagenes');
if ~exist(rutaImagenes, 'dir')  
    mkdir(rutaImagenes);  
end

% Crear nombre único y – si es TIFF – convertirla a PNG
timestamp = datestr(now,'yyyymmdd_HHMMSS');
[~,~, ext] = fileparts(file);     
origen = fullfile(path, file);                 
% --- si es tif/tiff => leer y escribir como PNG
if any(strcmpi(ext,{'.tif','.tiff'}))
    nombreArchivo = ['foto_' timestamp '.png'];            % nuevo ext
    rutaRelativa  = fullfile('imagenes', nombreArchivo);   % para el XML
    rutaDestino   = fullfile(rutaProyecto, rutaRelativa);  % destino real
    
    im  = imread(origen);
    imwrite(im, rutaDestino, 'png');           % conversión
else                                              % jpg / png ya válidas
    nombreArchivo = ['foto_' timestamp ext];
    rutaRelativa  = fullfile('imagenes', nombreArchivo);
    rutaDestino   = fullfile(rutaProyecto, rutaRelativa);
    
    copyfile(origen, rutaDestino);              % copia sin convertir
end

% Cargar XML y localizar al paciente
xml = xmlread('pacientes.xml');
idBuscado = strtrim(lower(app.PatientIdEditField.Value));

pacientes          = xml.getElementsByTagName('Paciente');
pacienteEncontrado = [];

for k = 0:pacientes.getLength-1
    p = pacientes.item(k);
    idNodo = p.getElementsByTagName('ID').item(0);
    
    % nodos pueden estar vacíos → comprobamos
    if ~isempty(idNodo) && ~isempty(idNodo.getFirstChild)
        idXML = strtrim(lower(char(idNodo.getFirstChild.getData)));
    else
        idXML = '';
    end
    
    if strcmp(idXML, idBuscado)
        pacienteEncontrado = p;
        % Mostrar la imagen en el UIAxes
        img = imread(fullfile(path, file));
        imshow(img, 'Parent', app.UIAxes);
        break;
    end
end

if isempty(pacienteEncontrado)
    uialert(app.UIFigure,'Patient not found.','Error');
    return;
else

doc = pacienteEncontrado.getOwnerDocument;

historialNodes = pacienteEncontrado.getElementsByTagName('Historial');
if historialNodes.getLength == 0
    historialNodo = doc.createElement('Historial');
    pacienteEncontrado.appendChild(historialNodo);
else
    historialNodo = historialNodes.item(0);
end

% ────────────────── 2. Crear nueva <Entrada> ───────────────────────────
entradaNodo = doc.createElement('Entrada');

% ---- Fecha (desde tu DatePicker) ----
fechaTxt = datestr(app.DateDatePicker.Value,'yyyy-mm-dd');  % ajusta formato
fechaNodo = doc.createElement('Fecha');
fechaNodo.appendChild(doc.createTextNode(fechaTxt));
entradaNodo.appendChild(fechaNodo);

% ---- Resultado (opcional) ----------
%resultadoNodo = doc.createElement('Resultado');
%resultadoNodo.appendChild(doc.createTextNode('-'));   % o 'Positivo/Negativo'
%entradaNodo.appendChild(resultadoNodo);

% ---- Especie (opcional) -----------
%especieNodo = doc.createElement('Especie');
%especieNodo.appendChild(doc.createTextNode('-'));     % o la especie detectada
%entradaNodo.appendChild(especieNodo);

% ---- Foto (ruta relativa) ----------
fotoNodo = doc.createElement('Foto');
fotoNodo.appendChild(doc.createTextNode(rutaRelativa));
entradaNodo.appendChild(fotoNodo);

% Añadir la entrada al historial
historialNodo.appendChild(entradaNodo);

app.LastPacienteID = strtrim(lower(app.PatientIdEditField.Value));
app.LastFotoRuta   = strrep(rutaRelativa, '\', '/');  % normaliza barra para XML
app.LastFechaStr   = datestr(app.DateDatePicker.Value, 'yyyy-mm-dd'); 
% ────────────────── 3. Guardar XML y mostrar imagen ───────────────────
xmlwrite('pacientes.xml', doc);

% Mostrar la imagen en el UIAxes
imshow(imread(rutaDestino), 'Parent', app.UIAxes);
% Confirmación
uialert(app.UIFigure,'Photo and date saved in the record.','Success',"Icon","success");
end

        end

        % Button pushed function: DiagnoseButton
        function DiagnoseButtonPushed(app, event)
         app.LastPacienteID = app.PatientIdEditField.Value;  % Guarda el ID actual
         mostrarHistorialPaciente(app);
        xml = xmlread('pacientes.xml');

% Leer valores de identificación del paciente
idBuscado = strtrim(lower(app.PatientIdEditField.Value));  % o usa nombre/apellido si no hay ID

% Buscar el paciente por ID
pacientes = xml.getElementsByTagName('Paciente');
pacienteEncontrado = [];

for k = 0:pacientes.getLength-1
    paciente = pacientes.item(k);
    idNodo = paciente.getElementsByTagName('ID').item(0);  % si usas <ID>
    if isempty(idNodo)
        continue;
    end
    idTexto = strtrim(lower(char(idNodo.getFirstChild.getData)));

    if strcmp(idTexto, idBuscado)
        pacienteEncontrado = paciente;
        break;
    end
end

if isempty(pacienteEncontrado)
    uialert(app.UIFigure, 'Patient not found.', 'Error');
    return;
end

% Obtener la ruta de la imagen
entradas = pacienteEncontrado.getElementsByTagName('Entrada');
entradaCorrecta = [];

for j = 0:entradas.getLength-1
    entrada = entradas.item(j);
    
    % Obtener nodos Foto y Fecha
    nodoFoto = entrada.getElementsByTagName('Foto').item(0);
    nodoFecha = entrada.getElementsByTagName('Fecha').item(0);
    
    if isempty(nodoFoto) || isempty(nodoFecha)
        continue;
    end

    rutaFoto = strtrim(strrep(char(nodoFoto.getFirstChild.getData), '\', '/'));
    fechaEntrada = strtrim(char(nodoFecha.getFirstChild.getData));
    
    % Comparar con la foto y fecha recientemente usadas
    if strcmpi(rutaFoto, strtrim(app.LastFotoRuta)) && ...
       strcmpi(fechaEntrada, strtrim(app.LastFechaStr))
        entradaCorrecta = entrada;
        break;
    end
end

if isempty(entradaCorrecta)
    uialert(app.UIFigure, 'It was not found an entry with that date and photo.', 'Error');
    return;
end

% Obtener la ruta de la imagen desde la entrada correcta
fotoNodo = entradaCorrecta.getElementsByTagName('Foto').item(0);
rutaRelativa = char(fotoNodo.getFirstChild.getData);
rutaCompleta = fullfile(pwd, rutaRelativa);

% Leer imagen
I = rutaCompleta;
[Mout,Iout] = PREDECIR(I);  % tu función que preprocesa
resultadoDiagnostico= Mout;
pacientes = xml.getElementsByTagName('Paciente');
    entradaObjetivo = [];

    for k = 0:pacientes.getLength-1
        p = pacientes.item(k);

        idNodo = p.getElementsByTagName('ID').item(0);
        idXML  = strtrim(lower(char(idNodo.getFirstChild.getData)));

        if strcmp(idXML, app.LastPacienteID)
            % Buscar dentro del historial la <Entrada> que coincida
            entradas = p.getElementsByTagName('Entrada');

            for j = entradas.getLength-1 : -1 : 0  % recorrer de la última a la primera
                e = entradas.item(j);

                % Comparamos FOTO y FECHA
                fotoNodo  = e.getElementsByTagName('Foto').item(0);
                fechaNodo = e.getElementsByTagName('Fecha').item(0);
                fotoTxt  = strtrim(strrep(char(fotoNodo.getFirstChild.getData), '\', '/'));
                fechaTxt = strtrim(char(fechaNodo.getFirstChild.getData));
                
                fotoIgual  = strcmpi(fotoTxt, strtrim(app.LastFotoRuta));
                fechaIgual = strcmpi(fechaTxt, strtrim(app.LastFechaStr));
                
                if fotoIgual && fechaIgual
                    entradaObjetivo = e;
                    break;
                end
              
            end
        end
    end
    disp(entradaObjetivo);
    if isempty(entradaObjetivo)
        uialert(app.UIFigure, 'It was not found the entry to upload the diagnosis', 'Error');
        return;
    end

    % 3) Añadir o actualizar el nodo <Especie> ──────────────────────────
    doc = entradaObjetivo.getOwnerDocument;
    resultadoNodes = entradaObjetivo.getElementsByTagName('Resultado');

    if resultadoNodes.getLength > 0
        resultadoNodo = resultadoNodes.item(0);
        resultadoNodo.getFirstChild.setData(char(resultadoDiagnostico));
    else
        resultadoNodo = doc.createElement('Resultado');
        resultadoNodo.appendChild(doc.createTextNode(char(resultadoDiagnostico)));
        entradaObjetivo.appendChild(resultadoNodo);
    end
    nombreArchivo = ['procesada_' char(datetime('now','Format','yyyyMMdd_HHmmss')) '.jpg'];
    rutaRelativaImagen = fullfile('imagenesProcesadas', nombreArchivo);
    rutaCompletaImagen = fullfile(pwd, rutaRelativaImagen);
    
    % Crear carpeta si no existe
    if ~exist('imagenesProcesadas', 'dir')
        mkdir('imagenesProcesadas');
    end
    
    % Guardar la imagen
    imwrite(Iout, rutaCompletaImagen);
    
    % 2. Añadir el nodo al XML
    imagenNodes = entradaObjetivo.getElementsByTagName('FotoProcesada');
    
    if imagenNodes.getLength > 0
        imagenNodo = imagenNodes.item(0);
        imagenNodo.getFirstChild.setData(rutaRelativaImagen);
    else
        imagenNodo = doc.createElement('FotoProcesada');
        imagenNodo.appendChild(doc.createTextNode(rutaRelativaImagen));
        entradaObjetivo.appendChild(imagenNodo);
    end
    xmlwrite('pacientes.xml', xml);
    app.Label_3.Text = char(Mout);
    imshow(Iout, 'Parent', app.UIAxes2);

        end

        % Button pushed function: SpeciesButton
        function SpeciesButtonPushed(app, event)
if strcmp(app.Label_3.Text, 'Malaria')
xml = xmlread('pacientes.xml');

% Leer valores de identificación del paciente
idBuscado = strtrim(lower(app.PatientIdEditField.Value));  % o usa nombre/apellido si no hay ID

% Buscar el paciente por ID
pacientes = xml.getElementsByTagName('Paciente');
pacienteEncontrado = [];

for k = 0:pacientes.getLength-1
    paciente = pacientes.item(k);
    idNodo = paciente.getElementsByTagName('ID').item(0);  % si usas <ID>
    if isempty(idNodo)
        continue;
    end
    idTexto = strtrim(lower(char(idNodo.getFirstChild.getData)));

    if strcmp(idTexto, idBuscado)
        pacienteEncontrado = paciente;
        break;
    end
end

if isempty(pacienteEncontrado)
    uialert(app.UIFigure, 'Paciente no encontrado.', 'Error');
    return;
end

% Obtener la ruta de la imagen
entradas = pacienteEncontrado.getElementsByTagName('Entrada');
entradaCorrecta = [];

for j = 0:entradas.getLength-1
    entrada = entradas.item(j);
    
    % Obtener nodos Foto y Fecha
    nodoFoto = entrada.getElementsByTagName('Foto').item(0);
    nodoFecha = entrada.getElementsByTagName('Fecha').item(0);
    
    if isempty(nodoFoto) || isempty(nodoFecha)
        continue;
    end

    rutaFoto = strtrim(strrep(char(nodoFoto.getFirstChild.getData), '\', '/'));
    fechaEntrada = strtrim(char(nodoFecha.getFirstChild.getData));
    
    % Comparar con la foto y fecha recientemente usadas
    if strcmpi(rutaFoto, strtrim(app.LastFotoRuta)) && ...
       strcmpi(fechaEntrada, strtrim(app.LastFechaStr))
        entradaCorrecta = entrada;
        break;
    end
end

if isempty(entradaCorrecta)
    uialert(app.UIFigure, 'It was not found an entry with that image and date.', 'Error');
    return;
end

% Obtener la ruta de la imagen desde la entrada correcta
fotoNodo = entradaCorrecta.getElementsByTagName('Foto').item(0);
rutaRelativa = char(fotoNodo.getFirstChild.getData);
rutaCompleta = fullfile(pwd, rutaRelativa);

% Leer imagen
I = imread(rutaCompleta);
[Iprep,Iimg,Ipar] = preprocesado(I);  % tu función que preprocesa

if strlength(app.LastPacienteID) == 0 || strlength(app.LastFotoRuta) == 0
        uialert(app.UIFigure, ...
            'First upload a photo and select a date.', ...
            'whithout image');
        return;
end

    % 1) Ejecución del modelo (ya lo haces)  ─────────────────────────────
    % supondremos que la variable resultadoEspecie tiene la especie en texto
    resultadoEspecie = char(Iprep);   % o donde guardes la predicción final

    % 2) Localizar el paciente y la entrada correcta en el XML ──────────
    xml = xmlread('pacientes.xml');
    pacientes = xml.getElementsByTagName('Paciente');
    entradaObjetivo = [];

    for k = 0:pacientes.getLength-1
        p = pacientes.item(k);

        idNodo = p.getElementsByTagName('ID').item(0);
        idXML  = strtrim(lower(char(idNodo.getFirstChild.getData)));

        if strcmp(idXML, app.LastPacienteID)
            % Buscar dentro del historial la <Entrada> que coincida
            entradas = p.getElementsByTagName('Entrada');

            for j = entradas.getLength-1 : -1 : 0  % recorrer de la última a la primera
                e = entradas.item(j);

                % Comparamos FOTO y FECHA
                fotoNodo  = e.getElementsByTagName('Foto').item(0);
                fechaNodo = e.getElementsByTagName('Fecha').item(0);
                fotoTxt  = strtrim(strrep(char(fotoNodo.getFirstChild.getData), '\', '/'));
                fechaTxt = strtrim(char(fechaNodo.getFirstChild.getData));
                
                fotoIgual  = strcmpi(fotoTxt, strtrim(app.LastFotoRuta));
                fechaIgual = strcmpi(fechaTxt, strtrim(app.LastFechaStr));
                
                if fotoIgual && fechaIgual
                    entradaObjetivo = e;
                    break;
                end
              
            end
        end
    end
    disp(entradaObjetivo);
    if isempty(entradaObjetivo)
        uialert(app.UIFigure, 'It was not found an entry for this patient', 'Error');
        return;
    end

    % 3) Añadir o actualizar el nodo <Especie> ──────────────────────────
    doc = entradaObjetivo.getOwnerDocument;
    especieNodes = entradaObjetivo.getElementsByTagName('Especie');

    if especieNodes.getLength > 0
        especieNodo = especieNodes.item(0);
        especieNodo.getFirstChild.setData(char(resultadoEspecie));
    else
        especieNodo = doc.createElement('Especie');
        especieNodo.appendChild(doc.createTextNode(char(resultadoEspecie)));
        entradaObjetivo.appendChild(especieNodo);
    end
    doc = entradaObjetivo.getOwnerDocument;
    parasitoNodes = entradaObjetivo.getElementsByTagName('Parasitemia');

    if parasitoNodes.getLength > 0
        parasitoNodo = parasitoNodes.item(0);
        parasitoNodo.getFirstChild.setData(string(Ipar));
    else
        parasitoNodo = doc.createElement('Parasitemia');
        parasitoNodo.appendChild(doc.createTextNode(string(Ipar)));
        entradaObjetivo.appendChild(parasitoNodo);
    end
    xmlwrite('pacientes.xml', xml);
    disp (resultadoEspecie)
    app.Label_2.Text = char(resultadoEspecie);
    imshow(Iimg, 'Parent', app.UIAxes2);
    app.Label_4.Text = string(Ipar);
 else
     uialert(app.UIFigure, 'The patient is healthy', 'Error');
 end
    mostrarHistorialPaciente(app); 
        end

        % Image clicked function: Image_3
        function Image_3Clicked(app, event)
            delete(app);
            nuevaApp = LoginApp(inicioen);
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            delete(app);
            nuevaApp = LoginApp(inicioen);
        end

        % Button pushed function: NewpatientButton
        function NewpatientButtonPushed(app, event)
    app.PatientIdEditField.Value   = '';
    app.Label_4.Text  = '';
    app.Label_3.Text  = '';
    app.Label_2.Text = '';
    app.DateDatePicker.Value = NaT;
    cla(app.UIAxes);
    cla(app.UIAxes2);
        end

        % Image clicked function: Image2_3
        function Image2_3Clicked(app, event)
            filePath = fullfile(pwd, 'InstruccionesPlasmoScan.pdf');
web(filePath, '-browser');
        end

        % Image clicked function: Image2_2
        function Image2_2Clicked(app, event)
            filePath = fullfile(pwd, 'InstruccionesPlasmoScan.pdf');
web(filePath, '-browser');
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            filePath = fullfile(pwd, 'InstruccionesPlasmoScan.pdf');
web(filePath, '-browser');
        end

        % Image clicked function: Image3_3
        function Image3_3Clicked(app, event)
            delete(app);
            nuevaApp = LoginApp(etiqueta);
        end

        % Image clicked function: Image3_2
        function Image3_2Clicked(app, event)
            delete(app);
            nuevaApp = LoginApp(etiqueta);
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            delete(app);
            nuevaApp = LoginApp(etiqueta);
        end

        % Image clicked function: Image_4
        function Image_4Clicked(app, event)
             delete(app);
            nuevaApp = LoginApp(inicioen);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataregistrationTab = uitab(app.TabGroup);
            app.DataregistrationTab.Title = 'Data registration';
            app.PatientdataregistrationLabel = uilabel(app.DataregistrationTab);
            app.PatientdataregistrationLabel.Text = 'Patient data registration';
            app.NombreLabel = uilabel(app.DataregistrationTab);
            app.NombreLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.DataregistrationTab, 'text');
            app.ApellidoLabel = uilabel(app.DataregistrationTab);
            app.ApellidoLabel.Text = 'Surname';
            app.SurnameEditField = uieditfield(app.DataregistrationTab, 'text');
            app.SexoLabel = uilabel(app.DataregistrationTab);
            app.SexoLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.DataregistrationTab);
            app.GenderDropDown.Items = {'Woman', 'Man', 'Other'};
            app.GenderDropDown.Value = 'Woman';
            app.EdadLabel = uilabel(app.DataregistrationTab);
            app.EdadLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.DataregistrationTab, 'numeric');
            app.AgeEditField.Limits = [0 130];
            app.PatientidLabel = uilabel(app.DataregistrationTab);
            app.PatientidLabel.Text = 'Patiente Id';
            app.PatienteIdEditField = uieditfield(app.DataregistrationTab, 'text');
            app.SaveButton = uibutton(app.DataregistrationTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Image_3 = uiimage(app.DataregistrationTab);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @Image_3Clicked, true);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'logo2.png');
            app.Image2 = uiimage(app.DataregistrationTab);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3_3 = uiimage(app.DataregistrationTab);
            app.Image3_3.ImageClickedFcn = createCallbackFcn(app, @Image3_3Clicked, true);
            app.Image3_3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            app.DiagnoseTab = uitab(app.TabGroup);
            app.DiagnoseTab.Title = 'Diagnose';
            app.UIAxes = uiaxes(app.DiagnoseTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.DiagnoseTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.PatientdiagnosisLabel = uilabel(app.DiagnoseTab);
            app.PatientdiagnosisLabel.Text = 'Patient diagnosis';
            app.IdpacienteLabel = uilabel(app.DiagnoseTab);
            app.IdpacienteLabel.Text = 'Patient Id';
            app.PatientIdEditField = uieditfield(app.DiagnoseTab, 'text');
            app.Label = uilabel(app.DiagnoseTab);
            app.Label.Text = '';
            app.UploadphotoButton = uibutton(app.DiagnoseTab, 'push');
            app.UploadphotoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadphotoButtonPushed2, true);
            app.UploadphotoButton.Text = 'Upload photo';
            app.DiagnoseButton = uibutton(app.DiagnoseTab, 'push');
            app.DiagnoseButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnoseButtonPushed, true);
            app.DiagnoseButton.Text = 'Diagnose';
            app.SpeciesButton = uibutton(app.DiagnoseTab, 'push');
            app.SpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @SpeciesButtonPushed, true);
            app.SpeciesButton.Text = 'Species';
            app.Label_2 = uilabel(app.DiagnoseTab);
            app.Label_2.Text = '';
            app.Image_2 = uiimage(app.DiagnoseTab);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'logo2.png');
            app.DateDatePickerLabel = uilabel(app.DiagnoseTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.DiagnoseTab);
            app.ResultsLabel = uilabel(app.DiagnoseTab);
            app.ResultsLabel.Text = 'Results';
            app.Label_3 = uilabel(app.DiagnoseTab);
            app.Label_3.Text = '';
            app.DiagnoseLabel = uilabel(app.DiagnoseTab);
            app.DiagnoseLabel.Text = 'Diagnose:';
            app.SpeciesLabel = uilabel(app.DiagnoseTab);
            app.SpeciesLabel.Text = 'Species:';
            app.ParasitemiaLabel = uilabel(app.DiagnoseTab);
            app.ParasitemiaLabel.Text = 'Parasitemia:';
            app.Label_4 = uilabel(app.DiagnoseTab);
            app.Label_4.Text = '';
            app.NewpatientButton = uibutton(app.DiagnoseTab, 'push');
            app.NewpatientButton.ButtonPushedFcn = createCallbackFcn(app, @NewpatientButtonPushed, true);
            app.NewpatientButton.Text = 'New patient';
            app.Image2_2 = uiimage(app.DiagnoseTab);
            app.Image2_2.ImageClickedFcn = createCallbackFcn(app, @Image2_2Clicked, true);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3_2 = uiimage(app.DiagnoseTab);
            app.Image3_2.ImageClickedFcn = createCallbackFcn(app, @Image3_2Clicked, true);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            app.HistorialTab = uitab(app.TabGroup);
            app.HistorialTab.Title = 'Historial';
            app.HistorialTable = uitable(app.HistorialTab);
            app.HistorialTable.ColumnName = {'ID'; 'Date'; 'Result'; 'Species'};
            app.HistorialTable.RowName = {};
            app.PatientrecordLabel = uilabel(app.HistorialTab);
            app.PatientrecordLabel.Text = 'Patient record';
            app.Image2_3 = uiimage(app.HistorialTab);
            app.Image2_3.ImageClickedFcn = createCallbackFcn(app, @Image2_3Clicked, true);
            app.Image2_3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3 = uiimage(app.HistorialTab);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            app.Image_4 = uiimage(app.HistorialTab);
            app.Image_4.ImageClickedFcn = createCallbackFcn(app, @Image_4Clicked, true);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'logo2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = medicoen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end