classdef registrarse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        Image3                     matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        Hyperlink                  matlab.ui.control.Hyperlink
        YatienecuentaLabel         matlab.ui.control.Label
        RegistrodeusuarioLabel     matlab.ui.control.Label
        EspecialidadDropDown       matlab.ui.control.DropDown
        EspecialidadDropDownLabel  matlab.ui.control.Label
        ContraseaEditField         matlab.ui.control.EditField
        ContraseaLabel             matlab.ui.control.Label
        UsuarioEditField           matlab.ui.control.EditField
        UsuarioLabel               matlab.ui.control.Label
        ApellidoEditField          matlab.ui.control.EditField
        ApellidoLabel              matlab.ui.control.Label
        NombreEditField            matlab.ui.control.EditField
        NombreLabel                matlab.ui.control.Label
        RegistrarseButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(iniciosesion);
        end

        % Button pushed function: RegistrarseButton
        function RegistrarseButtonPushed(app, event)
    archivo = fullfile(pwd, 'usuarios.xlsx');  % Se guarda en la carpeta actual
    contrasea = app.ContraseaEditField.Value;

     % Expresión regular para validar contraseña segura
     patron = '^(?=.*[a-z])(?=.*[A-Z])(?=.*\d).{8,}$';
if isempty(regexp(contrasea, patron, 'once'))
    uialert(app.UIFigure, ['La contraseña debe tener al menos:' newline ...
        '- 8 caracteres' newline ...
        '- Una letra mayúscula' newline ...
        '- Una letra minúscula' newline ...
        '- Un número'], 'Contraseña no válida');
    return;
else
    % Crear nueva tabla con los datos del usuario
    nuevaFila = table(...
        string(app.NombreEditField.Value), ...
        string(app.ApellidoEditField.Value), ...
        string(app.UsuarioEditField.Value), ...
        string(app.ContraseaEditField.Value), ...
        string(app.EspecialidadDropDown.Value), ...
        'VariableNames', {'Nombre', 'Apellido', 'Usuario', 'Contraseña', 'Rol'});

    % Si el archivo ya existe, leemos y agregamos
      if ~isfile(archivo)
        writetable(nuevaFila, archivo, ...
                   'FileType','spreadsheet', ...
                   'WriteMode','overwritetable', ...
                   'WriteVariableNames', true);      % escribe encabezados
    else  %––– si SÍ existe ⇒ solo añade la fila, sin sobrescribir ––
        writetable(nuevaFila, archivo, ...
                   'FileType','spreadsheet', ...
                   'WriteMode','append', ...          % ← AQUÍ la magia
                   'WriteVariableNames', false);      % evita duplicar encabezado
      end
      uialert(app.UIFigure,"Usuario registrado correctamente.","Éxito");

     delete(app);
     nuevaApp = LoginApp(iniciosesion);
 end
    
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
             delete(app);
            nuevaApp = LoginApp(inicio);
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            filePath = fullfile(pwd, 'InstruccionesPlasmoScan.pdf');
web(filePath, '-browser');
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            delete(app);
            nuevaApp = LoginApp(etiqueta);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RegistrarseButton = uibutton(app.UIFigure, 'push');
            app.RegistrarseButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrarseButtonPushed, true);
            app.RegistrarseButton.Text = 'Registrarse';
            app.NombreLabel = uilabel(app.UIFigure);
            app.NombreLabel.Text = 'Nombre       ';
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            app.ApellidoLabel = uilabel(app.UIFigure);
            app.ApellidoLabel.Text = 'Apellido        ';
            app.ApellidoEditField = uieditfield(app.UIFigure, 'text');
            app.UsuarioLabel = uilabel(app.UIFigure);
            app.UsuarioLabel.Text = 'Usuario        ';
            app.UsuarioEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaLabel = uilabel(app.UIFigure);
            app.ContraseaLabel.Text = 'Contraseña  ';
            app.ContraseaEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaEditField.CharacterLimits = [8 Inf];
            app.EspecialidadDropDownLabel = uilabel(app.UIFigure);
            app.EspecialidadDropDownLabel.Text = 'Especialidad';
            app.EspecialidadDropDown = uidropdown(app.UIFigure);
            app.EspecialidadDropDown.Items = {'Médico', 'Técnico de laboratorio'};
            app.EspecialidadDropDown.Value = 'Médico';
            app.RegistrodeusuarioLabel = uilabel(app.UIFigure);
            app.RegistrodeusuarioLabel.Text = 'Registro de usuario';
            app.YatienecuentaLabel = uilabel(app.UIFigure);
            app.YatienecuentaLabel.Text = '¿Ya tiene cuenta?';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Iniciar sesión';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = registrarse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end