classdef registrarseeu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Hyperlink                       matlab.ui.control.Hyperlink
        KontuadaukazuLabel              matlab.ui.control.Label
        ErabiltzaileenerregistroaLabel  matlab.ui.control.Label
        EspezialitateaDropDown          matlab.ui.control.DropDown
        EspezialitateaDropDownLabel     matlab.ui.control.Label
        PasahitzaEditField              matlab.ui.control.EditField
        ContraseaLabel                  matlab.ui.control.Label
        ErabiltzaileaEditField          matlab.ui.control.EditField
        UsuarioLabel                    matlab.ui.control.Label
        AbizenaEditField                matlab.ui.control.EditField
        ApellidoLabel                   matlab.ui.control.Label
        IzenaEditField                  matlab.ui.control.EditField
        NombreLabel                     matlab.ui.control.Label
        ErregistratzeaButton            matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(iniciosesioneu); 
           
        end

        % Button pushed function: ErregistratzeaButton
        function ErregistratzeaButtonPushed(app, event)
   archivo = fullfile(pwd, 'usuarios.xlsx');  % Se guarda en la carpeta actual
    rolSeleccionado = app.SpecialtyDropDown.Value;
    
    switch rolSeleccionado
        case 'Medikua'
            rolEsp = 'Médico';
        case 'Laborategi teknikoa'
            rolEsp = 'Técnico de laboratorio';
    end
    % Crear nueva tabla con los datos del usuario
    nuevaTabla = table(...
        string(app.IzenaEditField.Value), ...
        string(app.AbizenaEditField.Value), ...
        string(app.ErabiltzaileaEditField.Value), ...
        string(app.PasahitzaEditField.Value), ...
        string(rolEsp), ...
        'VariableNames', {'Nombre', 'Apellido', 'Usuario', 'Contraseña', 'Rol'});

    % Si el archivo ya existe, leemos y agregamos
    if isfile(archivo)
        % Leer datos existentes y añadir el nuevo usuario
        datosExistentes = readtable(archivo);
        datosActualizados = [datosExistentes; nuevaTabla];
    else
        % No existe → usamos directamente la nueva tabla
        datosActualizados = nuevaTabla;
    end

    % Guardar en Excel (lo crea si no existe)
    writetable(datosActualizados, archivo);

    uialert(app.UIFigure, 'Erabiltzailea ondo erregistratua.');
    
       delete(app);
       nuevaApp = LoginApp(iniciosesioneu); 
        
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(inicioeu);
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            filePath = fullfile(pwd, 'InstruccionesPlasmoScan.pdf');
web(filePath, '-browser');
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            delete(app);
            nuevaApp = LoginApp(etiqueta);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo2.png');
            app.ErregistratzeaButton = uibutton(app.UIFigure, 'push');
            app.ErregistratzeaButton.ButtonPushedFcn = createCallbackFcn(app, @ErregistratzeaButtonPushed, true);
            app.ErregistratzeaButton.Text = 'Erregistratzea';
            app.NombreLabel = uilabel(app.UIFigure);
            app.NombreLabel.Text = 'Izena           ';
            app.IzenaEditField = uieditfield(app.UIFigure, 'text');
            app.ApellidoLabel = uilabel(app.UIFigure);
            app.ApellidoLabel.Text = 'Abizena        ';
            app.AbizenaEditField = uieditfield(app.UIFigure, 'text');
            app.UsuarioLabel = uilabel(app.UIFigure);
            app.UsuarioLabel.Text = 'Erabiltzailea  ';
            app.ErabiltzaileaEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaLabel = uilabel(app.UIFigure);
            app.ContraseaLabel.Text = 'Pasahitza      ';
            app.PasahitzaEditField = uieditfield(app.UIFigure, 'text');
            app.EspezialitateaDropDownLabel = uilabel(app.UIFigure);
            app.EspezialitateaDropDownLabel.Text = 'Espezialitatea';
            app.EspezialitateaDropDown = uidropdown(app.UIFigure);
            app.EspezialitateaDropDown.Items = {'Medikua', 'Laborategi teknikoa'};
            app.EspezialitateaDropDown.Value = 'Medikua';
            app.ErabiltzaileenerregistroaLabel = uilabel(app.UIFigure);
            app.ErabiltzaileenerregistroaLabel.Text = 'Erabiltzaileen erregistroa';
            app.KontuadaukazuLabel = uilabel(app.UIFigure);
            app.KontuadaukazuLabel.Text = 'Kontua daukazu?';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Sesioa hasi';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = registrarseeu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end