classdef tecnico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image3                      matlab.ui.control.Image
        Image2_4                    matlab.ui.control.Image
        TabGroup                    matlab.ui.container.TabGroup
        DiagnosticarTab             matlab.ui.container.Tab
        Image_2                     matlab.ui.control.Image
        Label_2                     matlab.ui.control.Label
        EspecieButton               matlab.ui.control.Button
        DiagnosticarButton          matlab.ui.control.Button
        SubirfotoButton             matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        ResultadosLabel             matlab.ui.control.Label
        IdpacienteEditField_2       matlab.ui.control.EditField
        IdpacienteLabel             matlab.ui.control.Label
        ApellidoEditField_2         matlab.ui.control.EditField
        ApellidoLabel_2             matlab.ui.control.Label
        NombreEditField_2           matlab.ui.control.EditField
        NombreLabel_2               matlab.ui.control.Label
        DiagnsticodelpacienteLabel  matlab.ui.control.Label
        PacienteadiagnosticarLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: SubirfotoButton
        function SubirfotoButtonPushed2(app, event)
            [file, path] = uigetfile({ ...
    '*.jpg;*.jpeg;*.png;*.tif;*.tiff', ...
    'Imágenes (*.jpg, *.jpeg, *.png, *.tif, *.tiff)'}, ...
    'Selecciona una imagen');

if isequal(file,0)
    uialert(app.UIFigure,'No se seleccionó ninguna imagen.','Aviso');
    return;
end


% Asegúrate de que exista la carpeta ./imagenes
rutaProyecto = fileparts(mfilename('fullpath'));
rutaImagenes = fullfile(rutaProyecto, 'imagenes');
if ~exist(rutaImagenes, 'dir')  
    mkdir(rutaImagenes);  
end

% Crear nombre único y – si es TIFF – convertirla a PNG
timestamp = datestr(now,'yyyymmdd_HHMMSS');
[~,~, ext] = fileparts(file);     
origen = fullfile(path, file);                 
% --- si es tif/tiff => leer y escribir como PNG
if any(strcmpi(ext,{'.tif','.tiff'}))
    nombreArchivo = ['foto_' timestamp '.png'];            % nuevo ext
    rutaRelativa  = fullfile('imagenes', nombreArchivo);   % para el XML
    rutaDestino   = fullfile(rutaProyecto, rutaRelativa);  % destino real
    
    im  = imread(origen);
    imwrite(im, rutaDestino, 'png');           % conversión
else                                              % jpg / png ya válidas
    nombreArchivo = ['foto_' timestamp ext];
    rutaRelativa  = fullfile('imagenes', nombreArchivo);
    rutaDestino   = fullfile(rutaProyecto, rutaRelativa);
    
    copyfile(origen, rutaDestino);              % copia sin convertir
end

% Cargar XML y localizar al paciente
xml = xmlread('pacientes.xml');
idBuscado = strtrim(lower(app.IdpacienteEditField_2.Value));
nombreBuscado   = strtrim(lower(app.NombreEditField_2.Value));
apellidoBuscado = strtrim(lower(app.ApellidoEditField_2.Value));

pacientes          = xml.getElementsByTagName('Paciente');
pacienteEncontrado = [];

for k = 0:pacientes.getLength-1
    p = pacientes.item(k);
    idNodo = p.getElementsByTagName('ID').item(0);
    nombreNodo   = p.getElementsByTagName('Nombre').item(0);
    apellidoNodo = p.getElementsByTagName('Apellido').item(0);
    
    % nodos pueden estar vacíos → comprobamos
    if ~isempty(nombreNodo)   && ~isempty(nombreNodo.getFirstChild)
        nombreXML = strtrim(lower(char(nombreNodo.getFirstChild.getData)));
    else
        nombreXML = '';
    end
    
    if ~isempty(apellidoNodo) && ~isempty(apellidoNodo.getFirstChild)
        apellidoXML = strtrim(lower(char(apellidoNodo.getFirstChild.getData)));
    else
        apellidoXML = '';
    end
    if ~isempty(idNodo) && ~isempty(idNodo.getFirstChild)
        idXML = strtrim(lower(char(idNodo.getFirstChild.getData)));
    else
        idXML = '';
    end
    
    if strcmp(nombreXML,   nombreBuscado) && ...
       strcmp(apellidoXML, apellidoBuscado)&& ...
       strcmp(idXML, idBuscado)
        pacienteEncontrado = p;
        % Mostrar la imagen en el UIAxes
        img = imread(fullfile(path, file));
        imshow(img, 'Parent', app.UIAxes);
        break;
    end
end

if isempty(pacienteEncontrado)
    uialert(app.UIFigure,'Paciente no encontrado.','Error');
    return;
end

% Crear <Foto> con la ruta recién creada
doc      = pacienteEncontrado.getOwnerDocument;
fotoNodo = doc.createElement('Foto');
fotoNodo.appendChild(doc.createTextNode(rutaRelativa));

% Si ya existía <Foto>, lo sobreescribimos
antiguas = pacienteEncontrado.getElementsByTagName('Foto');
if antiguas.getLength > 0
    pacienteEncontrado.replaceChild(fotoNodo, antiguas.item(0));
else
    pacienteEncontrado.appendChild(fotoNodo);
end
 RutaImagenActual= rutaDestino;
xmlwrite('pacientes.xml', xml);

% Confirmación
uialert(app.UIFigure,'Foto subida y guardada correctamente.', 'Éxito', "Icon","success");
        end

        % Button pushed function: EspecieButton
        function EspecieButtonPushed(app, event)
 xml = xmlread('pacientes.xml');

% Leer valores de identificación del paciente
idBuscado = strtrim(lower(app.IdpacienteEditField_2.Value));  % o usa nombre/apellido si no hay ID

% Buscar el paciente por ID
pacientes = xml.getElementsByTagName('Paciente');
pacienteEncontrado = [];

for k = 0:pacientes.getLength-1
    paciente = pacientes.item(k);
    idNodo = paciente.getElementsByTagName('ID').item(0);  % si usas <ID>
    if isempty(idNodo)
        continue;
    end
    idTexto = strtrim(lower(char(idNodo.getFirstChild.getData)));

    if strcmp(idTexto, idBuscado)
        pacienteEncontrado = paciente;
        break;
    end
end

if isempty(pacienteEncontrado)
    uialert(app.UIFigure, 'Paciente no encontrado.', 'Error');
    return;
end

% Obtener la ruta de la imagen
fotoNodo = pacienteEncontrado.getElementsByTagName('Foto').item(0);
if isempty(fotoNodo)
    uialert(app.UIFigure, 'Este paciente no tiene imagen registrada.', 'Aviso');
    return;
end

rutaRelativa = char(fotoNodo.getFirstChild.getData);
rutaCompleta = fullfile(pwd, rutaRelativa);

% Leer la imagen y preprocesarla
I = imread(rutaCompleta);
Iprep = preprocesado(I);  % tu función que preprocesa

% disp(class(resultado))
% disp(size(resultado))

% Mostrar en un Label
app.Label_2.Text = ['Especie detectada: ', char(Iprep)];
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            delete(app);
            nuevaApp = LoginApp(inicio);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DiagnosticarTab = uitab(app.TabGroup);
            app.DiagnosticarTab.Title = 'Diagnosticar';
            app.UIAxes = uiaxes(app.DiagnosticarTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PacienteadiagnosticarLabel = uilabel(app.DiagnosticarTab);
            app.PacienteadiagnosticarLabel.Text = 'Paciente a diagnosticar';
            app.DiagnsticodelpacienteLabel = uilabel(app.DiagnosticarTab);
            app.DiagnsticodelpacienteLabel.Text = 'Diagnóstico del paciente';
            app.NombreLabel_2 = uilabel(app.DiagnosticarTab);
            app.NombreLabel_2.Text = 'Nombre     ';
            app.NombreEditField_2 = uieditfield(app.DiagnosticarTab, 'text');
            app.ApellidoLabel_2 = uilabel(app.DiagnosticarTab);
            app.ApellidoLabel_2.Text = 'Apellido     ';
            app.ApellidoEditField_2 = uieditfield(app.DiagnosticarTab, 'text');
            app.IdpacienteLabel = uilabel(app.DiagnosticarTab);
            app.IdpacienteLabel.Text = 'Id paciente ';
            app.IdpacienteEditField_2 = uieditfield(app.DiagnosticarTab, 'text');
            app.ResultadosLabel = uilabel(app.DiagnosticarTab);
            app.ResultadosLabel.Text = 'Resultados';
            app.Label = uilabel(app.DiagnosticarTab);
            app.Label.Text = '';
            app.SubirfotoButton = uibutton(app.DiagnosticarTab, 'push');
            app.SubirfotoButton.ButtonPushedFcn = createCallbackFcn(app, @SubirfotoButtonPushed2, true);
            app.SubirfotoButton.Text = 'Subir foto';
            app.DiagnosticarButton = uibutton(app.DiagnosticarTab, 'push');
            app.DiagnosticarButton.Text = 'Diagnosticar';
            app.EspecieButton = uibutton(app.DiagnosticarTab, 'push');
            app.EspecieButton.ButtonPushedFcn = createCallbackFcn(app, @EspecieButtonPushed, true);
            app.EspecieButton.Text = ' Especie';
            app.Label_2 = uilabel(app.DiagnosticarTab);
            app.Label_2.Text = '';
            app.Image_2 = uiimage(app.DiagnosticarTab);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'WhatsApp Image 2025-05-21 at 13.05.04.png');
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tecnico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end