classdef tecnicoen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image3                   matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        TabGroup                 matlab.ui.container.TabGroup
        DiagnoseTab              matlab.ui.container.Tab
        DiagnosethepatientLabel  matlab.ui.control.Label
        DiagnoseButton           matlab.ui.control.Button
        UploadphotoButton_2      matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UploadphotoButtonPushed(app, event)
    [archivo, ruta] = uigetfile({'*.jpg;*.png;*.jpeg','Imágenes (*.jpg, *.png)'});
    
    if isequal(archivo, 0)
        return; % Usuario canceló
    end

    img = imread(fullfile(ruta, archivo));
    imshow(img, 'Parent', app.UIAxes);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(inicioen);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DiagnoseTab = uitab(app.TabGroup);
            app.DiagnoseTab.Title = 'Diagnose';
            app.UIAxes = uiaxes(app.DiagnoseTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image = uiimage(app.DiagnoseTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.UploadphotoButton_2 = uibutton(app.DiagnoseTab, 'push');
            app.UploadphotoButton_2.Text = 'Upload photo';
            app.DiagnoseButton = uibutton(app.DiagnoseTab, 'push');
            app.DiagnoseButton.Text = 'Diagnose';
            app.DiagnosethepatientLabel = uilabel(app.DiagnoseTab);
            app.DiagnosethepatientLabel.Text = 'Diagnose the patient';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tecnicoen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end