classdef tecnicoeu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PazientearendiagnostikoaLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        DiagnostikatuTab               matlab.ui.container.Tab
        DIagnostikoaButton             matlab.ui.control.Button
        ArgazkiaigoButton_2            matlab.ui.control.Button
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ArgazkiaigoButtonPushed(app, event)
    [archivo, ruta] = uigetfile({'*.jpg;*.png;*.jpeg','Imágenes (*.jpg, *.png)'});
    
    if isequal(archivo, 0)
        return; % Usuario canceló
    end

    img = imread(fullfile(ruta, archivo));
    imshow(img, 'Parent', app.UIAxes);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            delete(app);
            nuevaApp = LoginApp(inicioeu);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DiagnostikatuTab = uitab(app.TabGroup);
            app.DiagnostikatuTab.Title = 'Diagnostikatu';
            app.UIAxes = uiaxes(app.DiagnostikatuTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image = uiimage(app.DiagnostikatuTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.Image2 = uiimage(app.DiagnostikatuTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 083416.png');
            app.Image3 = uiimage(app.DiagnostikatuTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2025-05-21 085038 (1).png');
            app.ArgazkiaigoButton_2 = uibutton(app.DiagnostikatuTab, 'push');
            app.ArgazkiaigoButton_2.Text = 'Argazkia igo';
            app.DIagnostikoaButton = uibutton(app.DiagnostikatuTab, 'push');
            app.DIagnostikoaButton.Text = 'DIagnostikoa';
            app.PazientearendiagnostikoaLabel = uilabel(app.UIFigure);
            app.PazientearendiagnostikoaLabel.Text = 'Pazientearen diagnostikoa';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tecnicoeu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end